classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DiameterinmmEditField       matlab.ui.control.NumericEditField
        DiameterinmmEditFieldLabel  matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        ClimupperboundSlider        matlab.ui.control.Slider
        ClimupperboundSliderLabel   matlab.ui.control.Label
        ClimlowerboundSlider        matlab.ui.control.Slider
        ClimlowerboundSliderLabel   matlab.ui.control.Label
        BinaryThresholdSlider       matlab.ui.control.Slider
        BinaryThresholdSliderLabel  matlab.ui.control.Label
        RenderEdgeCheckBox          matlab.ui.control.CheckBox
        Panel                       matlab.ui.container.Panel
        BScanWidthSpinner           matlab.ui.control.Spinner
        BScanWidthSpinnerLabel      matlab.ui.control.Label
        CatheterHeightSpinner       matlab.ui.control.Spinner
        CatheterHeightSpinnerLabel  matlab.ui.control.Label
        ArtifactLowerBoundSpinner   matlab.ui.control.Spinner
        ArtifactUpperBoundSpinner   matlab.ui.control.Spinner
        ArtifactBoundsLabel         matlab.ui.control.Label
        VariableNameEditField       matlab.ui.control.EditField
        VariableNameEditFieldLabel  matlab.ui.control.Label
        LoadScanButton              matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    % GUI
    %
    %   WRITTEN BY Torben

    properties (Access = private)
        % define global variables
        loaded;

        variableName;
        
        polarWidth;
        polarHeight;
        cartWidth;
        cartHeight;
        scanCount;
        

        denoiseIntensity;
        catheterHeight;
        artifactBounds;

        mScan;
        bScansPolar;
        bScansCart;
        lScan;
        
        bScanPolar;
        bScanCart;
        edgePolar;
        edgeCart;
        center;
        diameter;
    end
    
    methods (Access = private)

        function updaterenderbounds(app)
            % update slider limits and axes limits based on the b-scan
            % dimensions and count
            app.Slider.Limits = [1, app.scanCount];
            app.Slider.MajorTicks = 1:app.scanCount;
            app.Slider.MajorTickLabels = string(app.Slider.MajorTicks);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;

            app.UIAxes.XLim = [1, app.cartWidth];
            app.UIAxes.YLim = [1, app.cartHeight];
            app.UIAxes2.XLim = [1, app.scanCount];
            app.UIAxes2.YLim = [1, app.cartHeight];
        end
        
        function render(app)
            % display b-scan, l-scan and diameter
            app.DiameterinmmEditField.Value = app.diameter.millimeter;

            imagesc(app.UIAxes, app.bScanCart);
            clim(app.UIAxes, [app.ClimlowerboundSlider.Value, app.ClimupperboundSlider.Value]);
            hold(app.UIAxes, "on");
            if app.RenderEdgeCheckBox.Value
                scatter(app.UIAxes, app.edgeCart.x, app.edgeCart.y, 1, "red");
            end
            imagesc(app.UIAxes2, app.lScan);
            clim(app.UIAxes2, [app.ClimlowerboundSlider.Value, app.ClimupperboundSlider.Value]);
        end

        function update(app)
            % compute edge and diameter for the b-scan at the
            % slider-position
            app.bScanPolar = app.bScansPolar(:, :, app.Slider.Value);
            app.bScanCart = app.bScansCart(:, :, app.Slider.Value);
            [app.edgePolar, app.edgeCart] = detectedge(app.bScanPolar, app.BinaryThresholdSlider.Value);
            app.center = getcenter(app.edgeCart);
            app.diameter = getdiameter(app.edgePolar);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./functions/');

            app.loaded = 0;

            colormap(app.UIAxes, "gray");
            colormap(app.UIAxes2, "gray");

            app.variableName = "";
            app.polarHeight = 0;
            app.polarWidth = 0;
            app.cartWidth = 0;
            app.cartHeight = 0;
            app.scanCount = 0;

            app.denoiseIntensity = 60;
            app.catheterHeight = 102;
            app.artifactBounds = [221, 227];

            app.mScan = NaN;
            app.bScansPolar = NaN;
            app.bScansCart = NaN;
            app.lScan = NaN;

            app.bScanPolar = NaN;
            app.bScanCart = NaN;
            app.edgePolar = NaN;
            app.edgeCart = NaN;
            app.center.x = 0;
            app.center.y = 0;
            app.diameter = 0;


        end

        % Value changed function: VariableNameEditField
        function VariableNameEditFieldValueChanged(app, event)

        end

        % Button pushed function: LoadScanButton
        function LoadScanButtonPushed(app, event)
            filepath = uigetfile;
            if app.VariableNameEditField.Value == ""
                app.mScan = loadmscan(filepath);
            else
                app.mScan = loadmscan(filepath, app.VariableNameEditField.Value);
            end
            app.mScan = removecatheter(app.mScan, app.CatheterHeightSpinner.Value);
            app.mScan = removeartifact(app.mScan, [app.ArtifactLowerBoundSpinner.Value, app.ArtifactUpperBoundSpinner.Value]);
            app.bScansPolar = getbscans(app.mScan, app.BScanWidthSpinner.Value);

            clear app.mScan;

            [app.polarHeight, app.polarWidth, app.scanCount] = size(app.bScansPolar);
            app.cartHeight = 2*app.polarHeight + 1;
            app.cartWidth = app.cartHeight;

            app.bScansCart = zeros(app.cartHeight, app.cartWidth, app.scanCount);
            for i = 1:app.scanCount
                app.bScansPolar(:, :, i) = denoise(app.bScansPolar(:, :, i));
                app.bScansCart(:, :, i) = polartocartesian(app.bScansPolar(:, :, i));
            end


            app.updaterenderbounds;

            app.update;
            app.lScan = getlscan(app.bScansCart, app.center);
            
            app.loaded = 1;

            app.render;


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if app.loaded
                app.Slider.Value = round(app.Slider.Value);
            
                app.update;
                app.render;
            end
        end

        % Value changed function: BinaryThresholdSlider
        function BinaryThresholdSliderValueChanged(app, event)
            if app.loaded
                app.update;
                app.lScan = getlscan(app.bScansCart, app.center);
                app.render;
            end
            
        end

        % Value changed function: RenderEdgeCheckBox
        function RenderEdgeCheckBoxValueChanged(app, event)
            if app.loaded
                app.render;
            end
        end

        % Value changed function: ClimlowerboundSlider
        function ClimlowerboundSliderValueChanged(app, event)
            if app.ClimlowerboundSlider.Value > app.ClimupperboundSlider.Value
                app.ClimlowerboundSlider.Value = app.ClimupperboundSlider.Value;
            end
            if app.loaded
                app.render;
            end
            
        end

        % Value changed function: ClimupperboundSlider
        function ClimupperboundSliderValueChanged(app, event)
            if app.ClimupperboundSlider.Value < app.ClimlowerboundSlider.Value
                app.ClimupperboundSlider.Value = app.ClimlowerboundSlider.Value;
            end
            if app.loaded
                app.render;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'B-Scan')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'L-Scan')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.LoadScanButton = uibutton(app.Panel, 'push');
            app.LoadScanButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScanButtonPushed, true);
            app.LoadScanButton.Text = 'Load Scan';
            app.VariableNameEditFieldLabel = uilabel(app.Panel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.VariableNameEditField = uieditfield(app.Panel, 'text');
            app.VariableNameEditField.ValueChangedFcn = createCallbackFcn(app, @VariableNameEditFieldValueChanged, true);
            app.VariableNameEditField.Value = 'mscancut';
            app.ArtifactBoundsLabel = uilabel(app.Panel);
            app.ArtifactBoundsLabel.Text = 'Artifact Bounds';
            app.ArtifactUpperBoundSpinner = uispinner(app.Panel);
            app.ArtifactUpperBoundSpinner.Limits = [1 Inf];
            app.ArtifactUpperBoundSpinner.ValueDisplayFormat = '%.0f';
            app.ArtifactUpperBoundSpinner.Value = 227;
            app.ArtifactLowerBoundSpinner = uispinner(app.Panel);
            app.ArtifactLowerBoundSpinner.Limits = [1 Inf];
            app.ArtifactLowerBoundSpinner.ValueDisplayFormat = '%.0f';
            app.ArtifactLowerBoundSpinner.Value = 221;
            app.CatheterHeightSpinnerLabel = uilabel(app.Panel);
            app.CatheterHeightSpinnerLabel.Text = 'Catheter Height';
            app.CatheterHeightSpinner = uispinner(app.Panel);
            app.CatheterHeightSpinner.Limits = [1 Inf];
            app.CatheterHeightSpinner.Value = 102;
            app.BScanWidthSpinnerLabel = uilabel(app.Panel);
            app.BScanWidthSpinnerLabel.Text = 'B-Scan Width';
            app.BScanWidthSpinner = uispinner(app.Panel);
            app.BScanWidthSpinner.Limits = [1 Inf];
            app.BScanWidthSpinner.ValueDisplayFormat = '%.0f';
            app.BScanWidthSpinner.Value = 14800;
            app.Panel_2 = uipanel(app.UIFigure);
            app.RenderEdgeCheckBox = uicheckbox(app.Panel_2);
            app.RenderEdgeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RenderEdgeCheckBoxValueChanged, true);
            app.RenderEdgeCheckBox.Tag = 'renderEdge';
            app.RenderEdgeCheckBox.Text = 'Render Edge';
            app.RenderEdgeCheckBox.Value = true;
            app.BinaryThresholdSliderLabel = uilabel(app.Panel_2);
            app.BinaryThresholdSliderLabel.Text = 'Binary Threshold';
            app.BinaryThresholdSlider = uislider(app.Panel_2);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.BinaryThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.BinaryThresholdSlider.Value = 0.69;
            app.ClimlowerboundSliderLabel = uilabel(app.Panel_2);
            app.ClimlowerboundSliderLabel.Text = 'Clim lower bound';
            app.ClimlowerboundSlider = uislider(app.Panel_2);
            app.ClimlowerboundSlider.Limits = [0 1];
            app.ClimlowerboundSlider.ValueChangedFcn = createCallbackFcn(app, @ClimlowerboundSliderValueChanged, true);
            app.ClimlowerboundSlider.Value = 0.55;
            app.ClimupperboundSliderLabel = uilabel(app.Panel_2);
            app.ClimupperboundSliderLabel.Text = 'Clim upper bound';
            app.ClimupperboundSlider = uislider(app.Panel_2);
            app.ClimupperboundSlider.Limits = [0 1];
            app.ClimupperboundSlider.ValueChangedFcn = createCallbackFcn(app, @ClimupperboundSliderValueChanged, true);
            app.ClimupperboundSlider.Value = 0.75;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 2];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.DiameterinmmEditFieldLabel = uilabel(app.UIFigure);
            app.DiameterinmmEditFieldLabel.Text = 'Diameter in mm';
            app.DiameterinmmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end